classdef noteIdentifierGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        NoteidentifiedLabel  matlab.ui.control.Label
        NoteLabel            matlab.ui.control.Label
        Switch               matlab.ui.control.ToggleSwitch
        RightPanel           matlab.ui.container.Panel
        Meter                audio.ui.control.Meter
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        audioTimer
        deviceReader
        audioBuffer
        hopSize
        frameLength
        timePlotHandle
        fftPlotHandle
    end
    
    methods (Access = private)
        
        function audioStep(app)
            try
                n = 0:88;
                fundamentals = 27.5 * 2.^(n / 12);
                notes = {'C', 'C#/Db', 'D', 'D#/Eb', 'E', 'F', ...
                         'F#/Gb', 'G', 'G#/Ab', 'A', 'A#/Bb', 'B'};
                % 
                % app.deviceReader = audioDeviceReader( ...
                %     'SampleRate', 44100, ...
                %     'NumChannels', 1, ...
                %     'Device', 'Default');
        
                audioframe = app.deviceReader();
                app.audioBuffer(1:end-app.hopSize) = app.audioBuffer(app.hopSize+1:end);
                app.audioBuffer(end-app.hopSize+1:end) = audioframe;

                app.Meter.Value = rms(app.audioBuffer);
                drawnow limitrate

                app.UIAxes.YLim = [-1 1];
                if isempty(app.timePlotHandle) || ~isvalid(app.timePlotHandle)
                    app.timePlotHandle = plot(app.UIAxes, app.audioBuffer);
                else
                    app.timePlotHandle.YData = app.audioBuffer;
                end
        
                if rms(app.audioBuffer) > 0.01
                    L = length(app.audioBuffer);
                    N = 2^nextpow2(L);
                    FFT = fft(app.audioBuffer, N);
                    FFTMagnitude = abs(FFT(1:N/2));
        
                    [~,position] = max(FFTMagnitude);
                    f = (app.deviceReader.SampleRate / N) * position;
                    % f = pitch(app.audioBuffer, app.deviceReader.SampleRate);
                    % f = f(1);
                    [~, noteIndex] = min(abs(fundamentals - f));
        
                    octave = floor((noteIndex + 9) / 12);
                    noteID = notes{mod(noteIndex + 8, 12) + 1};
                    note = noteID + string(octave);

                    fs = 44100;
                    freqs = linspace(0, fs/2, N/2);
                    if isempty(app.fftPlotHandle) || ~isvalid(app.fftPlotHandle)
                        app.fftPlotHandle = plot(app.UIAxes2, freqs, FFTMagnitude);
                    else
                        app.fftPlotHandle.YData = FFTMagnitude;
                    end
                    drawnow limitrate
                    
                    app.NoteLabel.Text = note;
                end
            catch ME
                disp(getReport(ME))
                if ~isempty(app.audioTimer) && isvalid(app.audioTimer)
                    stop(app.audioTimer);
                    delete(app.audioTimer);
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if app.Switch.Value == "On"
                fs = 44100;
                app.frameLength = 2^nextpow2(fs);
                app.hopSize = app.frameLength / 16;
                app.audioBuffer = zeros(app.frameLength, 1);
        
                app.deviceReader = audioDeviceReader('SampleRate', fs, ...
                                  'SamplesPerFrame', app.hopSize);
        
                app.audioTimer = timer( ...
                    'ExecutionMode', 'fixedSpacing', ...
                    'Period', app.hopSize/fs, ...
                    'TimerFcn', @(~,~)audioStep(app));
                start(app.audioTimer);

            else
                if ~isempty(app.audioTimer) && isvalid(app.audioTimer)
                    stop(app.audioTimer);
                    delete(app.audioTimer);
                end
                if ~isempty(app.deviceReader)
                    release(app.deviceReader);
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {242, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Switch = uiswitch(app.LeftPanel, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.NoteLabel = uilabel(app.LeftPanel);
            app.NoteLabel.Text = 'C4';
            app.NoteidentifiedLabel = uilabel(app.LeftPanel);
            app.NoteidentifiedLabel.Text = 'Note identified:';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Audio Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Audio Frequencies (FFT)')
            xlabel(app.UIAxes2, 'Frequencies (Hz)')
            ylabel(app.UIAxes2, 'Magnitude')
            zlabel(app.UIAxes2, 'Z')
            app.Meter = uiaudiometer(app.RightPanel);
            app.Meter.Limits = [0 0.1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = noteIdentifierGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end